classdef GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        axes1        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        text2        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to GUI (see VARARGIN)
%             
%             % Choose default command line output for GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            file = uigetfile('*.dat');
            %file_dat_event_01 = readtable('ECE6323_PROJECT_02_CTCHANNEL_EVENT_01_MAIN.dat');
            file_dat_event_01 = readtable(file);
            
            %% Information from cfg file
            % Channel 1, identifier "Burden_Voltage", no info about channel or circuit
            % monitoring, voltage units are V, there is offset.
            %Channel multipplier
            v_multiplier = 0.000038357;
            %Channel offset
            v_offset = -0.204407170;
            
            %The nominal frequency of the system is 60 Hz.
            frequency = 60;
            %There is only one sampling rate, which is 4799.9846400492Hz. There are 7200 samples.
            sampling_rate = 4799.9846400492;
            n_samples = 4700;
            
            sampling_period = 1/sampling_rate; %In seconds
            
            %% Data conversion
            time_vector = file_dat_event_01{:,2}/1000000; %seconds
            %Multiply the data by the coefficients
            voltage_vector = file_dat_event_01{:,3}*v_multiplier+v_offset ;
            %% Plot
            axes(app.axes1)
            plot(time_vector,voltage_vector);
            xlabel('Time (s)');ylabel('Voltage (V)');title('Measured voltage');
        end

        % Callback function
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            message = 'Relay says: "Hi!"';
            set(app.text5,'String',message);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI';
            app.axes1 = uiaxes(app.figure1);
            app.axes2 = uiaxes(app.figure1);
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Please select data file';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Select file';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end