classdef maparicio6_Project_2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        PleaseselectCOMTRADEfilesLabel  matlab.ui.control.Label
        SelectcfgfileButton             matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        SelectplotDropDownLabel         matlab.ui.control.Label
        SelectplotDropDown              matlab.ui.control.DropDown
        ResultsTextAreaLabel            matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        SelectdatfileButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        variables % Structure of variables

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectcfgfileButton
        function SelectcfgfileButtonPushed(app, event)
            file = uigetfile('*.cfg');
            
            cfg_id = fopen(file);        
            
            file_cfg_event = textscan(cfg_id, '%s', 'delimiter',','); 

                     
            %% Information from cfg file
            % Channel 1, identifier "Burden_Voltage", no info about channel or circuit
            % monitoring, voltage units are V, there is offset.
            
            %Channel multipplier
            app.variables.v_multiplier = str2double(cell2mat(file_cfg_event{1,1}(12)));
            
            %Channel offset
            app.variables.v_offset = str2double(cell2mat(file_cfg_event{1,1}(13)));   
            
            %Nominal frequency of the system.
            app.variables.frequency = str2double(cell2mat(file_cfg_event{1,1}(15)));
            
            %Sampling rate.
            app.variables.sampling_rate = str2double(cell2mat(file_cfg_event{1,1}(17)));
            
            %Number of samples. 
            app.variables.n_samples = str2double(cell2mat(file_cfg_event{1,1}(18)));
            
            %calculation of sampling period.
            app.variables.sampling_period = 1/app.variables.sampling_rate; %In seconds
            
            app.TextArea.Value = 'Now select a .dat file. Later, please wait a few seconds until the state estimation is performed.';
            app.TextArea.FontColor = [0.93,0.69,0.13];


        end

        % Button pushed function: SelectdatfileButton
        function SelectdatfileButtonPushed(app, event)
            file = uigetfile('*.dat');
            file_dat_event = readtable(file);

            %% Data conversion
            time_vector = file_dat_event{:,2}/1000000; %seconds
            %Multiply the data by the coefficients
            voltage_vector = file_dat_event{:,3}*app.variables.v_multiplier+app.variables.v_offset;
            
            %Plot
            ax = app.UIAxes;
            plot(ax,time_vector,voltage_vector); 
            
            
            %% State estimation
            % System constants            
            h  = 1/app.variables.sampling_rate ;
            
            n = 400;
            gm = 0.001; 
            L1 = 26.526e-6;
            L2 = 348e-6; 
            L3 = 348e-6; 
            M23 = 287e-6;
            gs1 = 1.9635;
            gs2 = 0.1497;
            gs3 = 0.1497;
            r1 = 0.005;
            r2 = 0.4469;
            r3 = 0.4469;
            Rb = 0.1;
            gb = 1/Rb;
            h0 = 0.1876;
            i0 = 6.09109;
            L0 = 2.36;
            
            % Define all X state vector
            syms  v1t v2t v3t v4t et ht y1t y2t y3t y4t ipt imt iL1t iL2t iL3t
            % Define all X state vector for the previous iteration
            syms  v1h v2h v3h v4h eh hh y1h y2h y3h y4h iph imh iL1h iL2h iL3h
                      
            % Rmatrix
            R_vec = [0.005 0.005 0.005 0.005 0.0005 0.0005 0.0005 0.005 0.005 0.0005 0.00005 0.00005 0.00005 0.00005 0.0003 0.05 0.05 0.05 0.05 0.05 1];
            R2_vec = R_vec.*R_vec;
            R = repmat(R2_vec,21,1).*eye(21);
            W = inv(R);
            
            x_est_prev =  zeros(15,1);
            x_est = zeros(size(voltage_vector,1),15);
            
            % For Chi Square estimation
            residuals_vec = zeros(1,21);
            residuals_mat = zeros(4700,21);
            residuals = zeros(size(voltage_vector,1),1); 
            results_chi2 = zeros(size(voltage_vector,1),1); 
            time_fault = 0;
            confidence_level = 0.1; 
            
            % for each data point of Vout 
            for v=1:size(voltage_vector,1)
                    disp(v);
                    
                    % Zmed
                    Vout = voltage_vector(v);
                    Ib =  -voltage_vector(v)/Rb; 
                    
                     if v==1
                          ibh =  0; 
                     else
                         ibh = -voltage_vector(v-1)/Rb;
                     end
                    Zmed = [Vout 0 0 0 0 0 0 0 0 0 0 0 0 0 0 Ib Ib Ib Ib Ib 0]'; 
                    
                    % Initialize variables for first iteration 
                    xk= zeros(15,1);
                    
                    v1h = x_est_prev(1);  v2h = x_est_prev(2); v3h = x_est_prev(3); v4h = x_est_prev(4); eh = x_est_prev(5); hh =x_est_prev(6); y1h  = x_est_prev(7); y2h = x_est_prev(8); 
                    y3h = x_est_prev(9); y4h = x_est_prev(10); iph = x_est_prev(11);  imh = x_est_prev(12);  iL1h = x_est_prev(13);  iL2h = x_est_prev(14);  iL3h = x_est_prev(15); 
                      
                    Dx = ones(15,1)*10000; % just a very high value to ensure that the algorithm enters in the while loop
                    % while loop until Dx is less than my tolerance
                    while max(Dx)>0.0001
                        
                            v1t = xk(1);  v2t = xk(2); v3t = xk(3); v4t = xk(4); et = xk(5); ht = xk(6); y1t  = xk(7); y2t = xk(8); 
                            y3t = xk(9); y4t = xk(10); ipt = xk(11);  imt = xk(12);  iL1t = xk(13);  iL2t = xk(14);  iL3t = xk(15); 
                    
                            % State vector 
                            % X = [ v1(t)  v2(t)  v3(t)    v4(t)     e(t)                              h(t)                                                 y1(t)                    y2(t)                       y3(t)                     y4(t)                       ip(t)         im(t)           iL1(t)                iL2(t)                     iL3(t)]
            
                            %Compute H matrix
                                      
                            H =      [  0        0            1        -1          0                                 0                                                      0                            0                              0                           0                              0               0                  0                         0                            0  ;                              %1
                                            0        0           0         0     -gm*h/2                          0                                                       0                            0                              0                           0                         h/2/n        -h/2     (h/2+gs1*L1)             0                            0  ;                                %2
                                            0        0           0         0      gm*h/2                           0                                                      0                             0                              0                           0                        -h/2/n        h/2                 0               (-h/2-gs2*L2)      gs2*M23  ;                       %3
                                            0        0           0         0     -gm*h/2                          0                                                       0                            0                              0                           0                          h/2/n       -h/2                0                -gs3*M23        (h/2+gs3*L3) ;                      %4
                                          -h/2   h/2        0         0   (h/2+r1*gm*h/2)           0                                                      0                             0                              0                           0                     -r1*h/2/n    r1*h/2           L1                      0                            0   ;                                 %5
                                           h/2     0        -h/2      0            0                                 0                                                      0                             0                              0                            0                             0              0                   0       (L2+r2*(h/2+gs2*L2))   (-M23-r2*gs2*M23) ;      %6
                                           0      -h/2         0      h/2          0                                 0                                                       0                            0                              0                            0                             0              0                   0        -M23*(1+r3*gs3)       (L3*(1+r3*gs3)+r3*h/2) ;      %7
                                           0        0     gb*h/2  -gb*h/2   0                                0                                                      0                              0                             0                            0                             0              0                   0               (h/2+gs2*L2)         -gs2*M23    ;                       %8
                                           0        0   -gb*h/2   gb*h/2   0                                 0                                                      0                              0                             0                           0                              0              0                    0                 gs3*M23            (-h/2-gs3*L3);                       %9
                                           0        0            0        0            h/2                             -1                                                     0                             0                              0                            0                             0              0                    0                        0                              0 ;                                %10
                                           0        0            0        0             0                 -1/(h0^2)*(2*ht)                                        1                              0                             0                            0                              0              0                   0                        0                               0 ;                                %11
                                           0        0            0        0             0                                 0                                                -(2*y1t)                      1                             0                            0                              0               0                    0                         0                              0 ;                          %12
                                           0        0            0        0             0                                 0                                                       0                     -(2*y2t)                       1                             0                             0              0                    0                        0                              0  ;                          %13
                                           0        0            0        0             0                                 0                                                   -(y3t)                        0                        -(y1t)                         1                             0              0                    0                        0                              0  ;                          %14
                                           0        0            0        0             0                  -(i0/h0)*y4t-1/L0                                       0                            0                             0                 -(i0/h0)*ht                      0              1                    0                        0                              0 ;                          %15
                                           0        0         -gb     gb             0                                  0                                                      0                            0                              0                           0                              0              0                    0                         0                              0 ;                       %16
                                           0        0           0        0             0                                    0                                                     0                            0                             0                           0                               0               0    (h/2+gs1*L1)*(2/h)     0                              0  ;                     %17
                                           0        0           0        0             0                                    0                                                     0                            0                             0                           0                               0               0                   0     (h/2+gs2*L2)*(2/h)   -gs2*M23*(2/h)    ;                     %18
                                           0        0           0        0             0                                    0                                                     0                             0                             0                           0                              0               0                   0         gs3*M23*(2/h)        (-h/2-gs3*L3)*(2/h)   ;                     %19
                                           0        0           0        0           gm                                 0                                                     0                             0                             0                           0                           -1/n            1                  0                        0                               0 ;                     %20
                                           0        0           0        1          0                                    0                                                     0                             0                             0                           0                               0               0                   0                         0                               0 ;
                                           ];
                                
                                       %Compute f(X)

                            
                                 f = [ v3t-v4t;                                                                                                                                                                          %1
                                        -gm*h/2*(et+eh)-h/2*(imt+imh)+h/2/n*(ipt+iph)+h/2*(iL1t+iL1h)+gs1*L1*(iL1t-iL1h);                                                                   %2
                                        gm*h/2*(et+eh)+h/2*(imt+imh)-h/2/n*(ipt+iph)-h/2*(iL2t+iL2h)-gs2*L2*(iL2t-iL2h)+gs2*M23*(iL3t-iL3h);                            %3  
                                        -gm*h/2*(et+eh)-h/2*(imt+imh)+h/2/n*(ipt+iph)+h/2*(iL3t+iL3h)+gs3*L3*(iL3t-iL3h)-gs3*M23*(iL2t-iL2h);                               %4
                                        -h/2*(v1t+v1h)+h/2*(v2t+v2h)+h/2*(et+eh)+L1*(iL1t-iL1h)+r1*gm*h/2*(et+eh)+r1*h/2*(imt+imh)-r1*h/2/n*(ipt+iph);                  %5  
                                        -h/2*(v3t+v3h)+h/2*(v1t+v1h)+r2*h/2*(iL2t+iL2h)+r2*gs2*L2*(iL2t-iL2h)-r2*gs2*M23*(iL3t-iL3h)+L2*(iL2t-iL2h)-M23*(iL3t-iL3h);        %6
                                        -h/2*(v2t+v2h)+h/2*(v4t+v4h)+r3*h/2*(iL3t+iL3h)+r3*gs3*L3*(iL3t-iL3h)-r3*gs3*M23*(iL2t-iL2h)+L3*(iL3t-iL3h)-M23*(iL2t-iL2h);            %7
                                        h/2*(iL2t+iL2h)+gs2*L2*(iL2t-iL2h)-gs2*M23*(iL3t-iL3h)+gb*h/2*(v3t+v3h)-gb*h/2*(v4t+v4h);                                                                           %8
                                        -h/2*(iL3t+iL3h)-gs3*L3*(iL3t-iL3h)+gs3*M23*(iL2t-iL2h)+gb*h/2*(v4t+v4h)-gb*h/2*(v3t+v3h);                                                              %9
                                        h/2*(et+eh)-(ht-hh);                                                                                                                                                                                                            %10
                                        y1t-(1/(h0^2))*(ht^2);                                     %11
                                        y2t-(y1t^2);                                            %12
                                        y3t-(y2t^2);                                            %13
                                        y4t-(y3t*y1t);                                    %14
                                        imt-(i0/h0)*ht*y4t-(1/L0)*(ht);           %15
                                        -gb*(v3t-v4t);                                                           %16
                                        (h/2*(iL1t+iL1h)+gs1*L1*(iL1t-iL1h))*(2/h)-ibh;                                                                     %17
                                        (h/2*(iL2t+iL2h)+gs2*L2*(iL2t-iL2h)-gs2*M23*(iL3t-iL3h))*(2/h)-ibh;                                 %18
                                        (-h/2*(iL3t+iL3h)-gs3*L3*(iL3t-iL3h)+gs3*M23*(iL2t-iL2h))*(2/h)-ibh;                    %19
                                        gm*et+imt-1/n*ipt;                               %20
                                        v4t                                                               %21
                                ];
            
                                % Compute delta X (Dx)
                                Dx  = inv(H'*W*H)*H'*W*(Zmed-f);
                                
                                % Compute estimation
                                xk1 = xk+Dx; 
                                
                                % Update vectors 
                                xk = xk1; 
                                
                                % Chi square test
                                
                                residuals_vec = (Zmed - f).^2; 

                                residuals_vec = residuals_vec';
                                residuals(v) = sum(residuals_vec./R2_vec); 
                                residuals_mat(v,:) = residuals_vec./R2_vec;
                                results_chi2(v) = 1-chi2cdf(residuals(v), 6);
                                
%                                 if results_chi2(v) <= 1-confidence_level
%                                     time_fault = time_vector(v);
%                                 end
                                
                    end
            
            x_est(v,:) = xk1;
            x_est_prev = x_est(v,:);
            
            end
            
            %Saving results 
            app.variables.x_est=x_est;
            app.variables.time_vector=time_vector;
            app.variables.voltage_vector=voltage_vector;
            app.variables.n=n;
            app.variables.Rb=Rb;
            app.variables.results_chi2=results_chi2;
            app.variables.residuals_mat=residuals_mat;
            
            
            app.TextArea.Value = 'State estimatiom completed. Results are available below. Proceed to select plot.';
            app.TextArea.FontColor = [0.39,0.83,0.07];
            
            v=1;
            while results_chi2(v) > (1-confidence_level)
                time_fault = time_vector(v);
                v = v+1;
            end
            
            if time_fault == 0
            str = fprintf('Results:\nThere is no fault in the system');
            end 
            
            if time_fault > 0
            result_str = sprintf('Results:\nThere is a fault in the system at time (s): %f', time_fault);
            end 
            
            app.ResultsTextArea.Value = result_str;
            
            
        end

        % Value changed function: SelectplotDropDown
        function SelectplotDropDownValueChanged(app, event)
            
            value = app.SelectplotDropDown.Value;
            
            ax2 = app.UIAxes_2;
            
            if value == "Estimated Ip"
                plot(ax2,app.variables.time_vector,app.variables.x_est(:,11));
                xlabel(ax2,'Time (s)');ylabel(ax2,'Current (A)');title(ax2,'Estimated Ip');
            end
            
            if value == "Difference between Ip and CT*Ib "
                plot(ax2,app.variables.time_vector,app.variables.x_est(:,11)-app.variables.n*app.variables.voltage_vector/app.variables.Rb);
                xlabel(ax2,'Time (s)');ylabel(ax2,'Current (A)');title(ax2,'Difference between Ip and CT*Ib ');
            end
            
            if value == "Linkage flux"
                plot(ax2,app.variables.time_vector,app.variables.x_est(:,6));
                xlabel(ax2,'Time (s)');ylabel(ax2,'Linkage flux (Wb)');title(ax2,'Linkage flux');
            end
            
            if value == "Chi Square test"
                plot(ax2,app.variables.time_vector,app.variables.results_chi2);
                xlabel(ax2,'Time (s)');ylabel(ax2,'Test succeeded');title(ax2,'Chi Square test');
                
            end
            
            if value == "Residuals"
                plot(ax2,app.variables.time_vector,app.variables.residuals_mat(:,1:21));
                xlabel(ax2,'Time (s)');ylabel(ax2,'Residuals');title(ax2,'Residuals');
                legend(ax2,{'1', '2', '3', '4', '5', '6', '7', '8', '9', '10',  '11',  '12',  '13' , '14', '15', '16','17','18','19','20','21'},'NumColumns',2)

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Start by selecting a .cfg file. '};
            app.PleaseselectCOMTRADEfilesLabel = uilabel(app.UIFigure);
            app.PleaseselectCOMTRADEfilesLabel.Text = 'Please select COMTRADE files';
            app.SelectcfgfileButton = uibutton(app.UIFigure, 'push');
            app.SelectcfgfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcfgfileButtonPushed, true);
            app.SelectcfgfileButton.Text = 'Select .cfg file';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.SelectplotDropDownLabel = uilabel(app.UIFigure);
            app.SelectplotDropDownLabel.Text = 'Select plot';
            app.SelectplotDropDown = uidropdown(app.UIFigure);
            app.SelectplotDropDown.Items = {'Estimated Ip', 'Difference between Ip and CT*Ib ', 'Linkage flux', 'Chi Square test', 'Residuals', 'None'};
            app.SelectplotDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectplotDropDownValueChanged, true);
            app.SelectplotDropDown.Value = 'None';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.SelectdatfileButton = uibutton(app.UIFigure, 'push');
            app.SelectdatfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdatfileButtonPushed, true);
            app.SelectdatfileButton.Text = 'Select .dat file';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Measured voltage (Vout)')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maparicio6_Project_2_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end